classdef Glaucoma_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        DiscSegmentationLabel       matlab.ui.control.Label
        CupSegmentationLabel        matlab.ui.control.Label
        BloodVesselsRemovedLabel    matlab.ui.control.Label
        IlluminationCorrectedLabel  matlab.ui.control.Label
        GraylevelImageLabel         matlab.ui.control.Label
        OriginalImageLabel          matlab.ui.control.Label
        GlaucomaDetectionLabel      matlab.ui.control.Label
        uibuttongroup2              matlab.ui.container.ButtonGroup
        EditField_2                 matlab.ui.control.EditField
        EditField                   matlab.ui.control.EditField
        Lamp                        matlab.ui.control.Lamp
        CDRLabel                    matlab.ui.control.Label
        uploadimage                 matlab.ui.control.Button
        addition                    matlab.ui.control.Button
        axes6                       matlab.ui.control.UIAxes
        axes4                       matlab.ui.control.UIAxes
        axes5                       matlab.ui.control.UIAxes
        axes3                       matlab.ui.control.UIAxes
        axes2                       matlab.ui.control.UIAxes
        axes1                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function untitled7_OpeningFcn(app, varargin)
            % --- Executes just before untitled7 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see Oapp.SubtractionButtonutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to untitled7 (see VARARGIN)
            
            % Choose default command line output for untitled7
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: addition
        function addition_Callback(app, event)
            a = getappdata(0,'a');
            gray_image = im2gray(a);
            imshow(gray_image, 'Parent',app.axes2);


            se = strel('disk', 30); % Adjust the structuring element based on the image characteristics
            background = imopen(gray_image, se);

            % Step 3: Subtract the estimated background from the grayscale image
            illumination_corrected = imsubtract(gray_image, background);

            % Step 4: Add a fixed DC level (adjust as needed)
            
            fixed_dc_level = 30;
            illumination_corrected = illumination_corrected + fixed_dc_level;
            imshow(illumination_corrected, 'Parent', app.axes3);


            se2 = strel('disk', 20);
            bv_removed = imclose(illumination_corrected, se2);
            imshow(bv_removed, 'Parent',app.axes4);
               
            
            normImage = im2double(bv_removed);

            % cup segmentation
            cup = imbinarize(normImage, 0.45);
            imshow(cup, 'Parent',app.axes5);

            disc = imbinarize(normImage, 0.35);
            imshow(disc, 'Parent',app.axes6);


            stats = regionprops(cup, 'Centroid', 'Area');

            % Find the circle with the maximum area (assuming there is only one circle)
            [maxArea, index] = max([stats.Area]);

            % Calculate the radius using the area of the circle (A = pi * r^2)
            radius = sqrt(maxArea / pi);

            % Calculate the area of the circle
            cup_area = pi * radius^2;

            stats = regionprops(disc, 'Centroid', 'Area');

            % Find the circle with the maximum area (assuming there is only one circle)
            [maxArea2, index2] = max([stats.Area]);

            % Calculate the radius using the area of the circle (A = pi * r^2)
            radius2 = sqrt(maxArea2 / pi);

            % Calculate the area of the circle
            disc_area = pi * radius2^2;
            % Calculate the ratio of the area of the cup to the disc
            ratio = cup_area / disc_area;
            text1 = sprintf(num2str(ratio));
            app.EditField.Value = text1;

            if ratio < 0.5 && ratio > 0
                app.Lamp.Color= 'green';
                app.EditField_2.Value = "Healthy Eye";
             
             elseif ratio >= 0.5 
                app.Lamp.Color='red';
                app.EditField_2.Value = "Glaucoma detected";
                
             end

        end

        % Button pushed function: uploadimage
        function uploadimage_Callback(app, event)
            % --- Executes on button press in uploadimage.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uploadimage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = uigetfile('.jpg');
            a = imread(a);
            axes(handles.axes1);
            imshow(a);
            setappdata(0, 'a', a);
            
           
        end

        % Callback function
        function AdjustImageButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            a = getappdata(0,'a');
            RGB1 = imadjust(a,[.2 .3 0; .6 .7 1],[]);
            axes(handles.axes1);
            imshow(RGB1)

            b = getappdata(0,'b');
            RGB2 = imadjust(b,[.2 .3 0; .6 .7 1],[]);
            axes(handles.axes2);
            imshow(RGB2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'untitled7';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XTick = [];
            app.axes2.YTick = [];
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.XTick = [];
            app.axes3.YTick = [];
            app.axes3.Tag = 'axes3';
            app.axes5 = uiaxes(app.figure1);
            app.axes5.XTick = [];
            app.axes5.YTick = [];
            app.axes4 = uiaxes(app.figure1);
            app.axes4.XTick = [];
            app.axes4.YTick = [];
            app.axes6 = uiaxes(app.figure1);
            app.axes6.XTick = [];
            app.axes6.YTick = [];
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.addition = uibutton(app.uibuttongroup2, 'push');
            app.addition.ButtonPushedFcn = createCallbackFcn(app, @addition_Callback, true);
            app.addition.Tag = 'addition';
            app.addition.Text = 'Detect Glaucoma';
            app.uploadimage = uibutton(app.uibuttongroup2, 'push');
            app.uploadimage.ButtonPushedFcn = createCallbackFcn(app, @uploadimage_Callback, true);
            app.uploadimage.Tag = 'uploadimage';
            app.uploadimage.Text = 'Upload Pic';
            app.CDRLabel = uilabel(app.uibuttongroup2);
            app.CDRLabel.Text = 'CDR =';
            app.Lamp = uilamp(app.uibuttongroup2);
            app.EditField = uieditfield(app.uibuttongroup2, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.uibuttongroup2, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.GlaucomaDetectionLabel = uilabel(app.figure1);
            app.GlaucomaDetectionLabel.Text = 'Glaucoma Detection';
            app.OriginalImageLabel = uilabel(app.figure1);
            app.OriginalImageLabel.Text = 'Original Image';
            app.GraylevelImageLabel = uilabel(app.figure1);
            app.GraylevelImageLabel.Text = 'Graylevel Image';
            app.IlluminationCorrectedLabel = uilabel(app.figure1);
            app.IlluminationCorrectedLabel.Text = 'Illumination Corrected';
            app.BloodVesselsRemovedLabel = uilabel(app.figure1);
            app.BloodVesselsRemovedLabel.Text = 'Blood Vessels Removed';
            app.CupSegmentationLabel = uilabel(app.figure1);
            app.CupSegmentationLabel.Text = 'Cup Segmentation';
            app.DiscSegmentationLabel = uilabel(app.figure1);
            app.DiscSegmentationLabel.Text = 'Disc Segmentation';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Glaucoma_Detection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)untitled7_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end